classdef new_matlab_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        VelocityControlradsPanel    matlab.ui.container.Panel
        Motor1Label                 matlab.ui.control.Label
        Motor1Slider                matlab.ui.control.Slider
        Motor2SliderLabel           matlab.ui.control.Label
        Motor2Slider                matlab.ui.control.Slider
        Motor3SliderLabel           matlab.ui.control.Label
        Motor3Slider                matlab.ui.control.Slider
        PositionControlradsPanel    matlab.ui.container.Panel
        Motor1Slider_2Label         matlab.ui.control.Label
        Motor1Slider_2              matlab.ui.control.Slider
        Motor2Slider_2Label         matlab.ui.control.Label
        Motor2Slider_2              matlab.ui.control.Slider
        Motor3Slider_2Label         matlab.ui.control.Label
        Motor3Slider_2              matlab.ui.control.Slider
        SensorValuesPanel           matlab.ui.container.Panel
        IRSensorGaugeLabel          matlab.ui.control.Label
        IRSensorGauge               matlab.ui.control.Gauge
        UltrasonicSensorGaugeLabel  matlab.ui.control.Label
        UltrasonicSensorGauge       matlab.ui.control.Gauge
        PotentiometerGaugeLabel     matlab.ui.control.Label
        PotentiometerGauge          matlab.ui.control.Gauge
        ControlMethodSwitchLabel    matlab.ui.control.Label
        ControlMethodSwitch         matlab.ui.control.ToggleSwitch
        ControlTypeSwitchLabel      matlab.ui.control.Label
        ControlTypeSwitch           matlab.ui.control.ToggleSwitch
        ButtonStateSwitchLabel      matlab.ui.control.Label
        ButtonStateSwitch           matlab.ui.control.Switch
    end

    
    properties (Access = private)
        serialHandler
        robot
        cmd
        updateTimer % Description
        sensorVals
    end
    
    methods (Access = private)
        
        function serialCallback(app,~)
            msg = fscanf(app.serialHandler);
            [irp, usp, potp, buttonp] = app.parseMessage(msg);
            app.updateSensorVals();
            
            % IF hardware button is pressed, do nothing
            if (buttonp == 1)
                app.ButtonStateSwitch.Value = 'Off';
                app.cmd.position = [];
                app.cmd.velocity = [];
                app.robot.set(app.cmd);
                return
            end
                
            app.ButtonStateSwitch.Value = 'On';
            if (strcmp(app.ControlMethodSwitch.Value, 'Sensor') == 1)
                app.cmd.position = [irp usp potp];
                app.cmd.velocity = [];
                app.robot.set(app.cmd);
            else
                if (strcmp(app.ControlTypeSwitch.Value, 'Position') == 1)
                    pos1 = app.Motor1Slider_2.Value;
                    pos2 = app.Motor2Slider_2.Value;
                    pos3 = app.Motor3Slider_2.Value;
                    
                    app.cmd.position = [pos1 pos2 pos3];
                    app.cmd.velocity = [];
                    app.robot.set(app.cmd);
                else
                    vel1 = app.Motor1Slider.Value;
                    vel2 = app.Motor2Slider.Value;
                    vel3 = app.Motor3Slider.Value;
                    
                    app.cmd.position = [];
                    app.cmd.velocity = [vel1 vel2 vel3];
                    app.robot.set(app.cmd);
                end
            end                            
        end
        
        function updateSensorVals(app)
            app.IRSensorGauge.Value = uint16(app.sensorVals(1));
            app.UltrasonicSensorGauge.Value = uint16(app.sensorVals(2));
            app.PotentiometerGauge.Value = uint16(app.sensorVals(3));
        end
        
        function [ir, us, pot, button] = parseMessage(app, msg)
            persistent irp usp potp buttonp
            split_vals = strsplit(msg,',');
            try
                % Parse values
                irp = cell2mat(split_vals(1));
                usp = cell2mat(split_vals(2));
                potp = cell2mat(split_vals(3));
                buttonp = cell2mat(split_vals(4));
                
                app.sensorVals = [str2double(irp) str2double(usp) str2double(potp) str2double(buttonp)];
        
                % Map to 2*pi range
                irp = str2double(irp) * 2 * pi / 60;
                usp = str2double(usp) * 2 * pi / 120;
                potp = 2*pi - str2double(potp) * 2 * pi / 1023;
                buttonp = str2double(buttonp);
%                 disp("ir: " + irp + "   us: " + usp + "  pot: " + potp)
            catch
            end
            ir = irp;
            us = usp;
            pot = potp;
            button = buttonp;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add heby to library path
            currentDir = fileparts(mfilename('fullpath'));
            addpath(fullfile(currentDir , 'lib', 'hebi'));
            
            fclose(instrfind)    
            app.serialHandler = serial("COM3", "BaudRate", 9600);
            fclose(app.serialHandler);
            fopen(app.serialHandler);
            
            set(app.serialHandler, 'BytesAvailableFcn', @(hObject, event) app.serialCallback(event));
            
            app.robot = HebiLookup.newGroupFromNames('RoboDutchman', {'joint1', 'joint2', 'joint3'});
            app.cmd = CommandStruct();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.serialHandler);    
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VelocityControlradsPanel = uipanel(app.UIFigure);
            app.VelocityControlradsPanel.Title = 'Velocity Control (rad/s)';
            app.Motor1Label = uilabel(app.VelocityControlradsPanel);
            app.Motor1Label.Text = 'Motor 1';
            app.Motor1Slider = uislider(app.VelocityControlradsPanel);
            app.Motor1Slider.Limits = [0 1];
            app.Motor2SliderLabel = uilabel(app.VelocityControlradsPanel);
            app.Motor2SliderLabel.Text = 'Motor 2';
            app.Motor2Slider = uislider(app.VelocityControlradsPanel);
            app.Motor2Slider.Limits = [0 1];
            app.Motor3SliderLabel = uilabel(app.VelocityControlradsPanel);
            app.Motor3SliderLabel.Text = 'Motor 3';
            app.Motor3Slider = uislider(app.VelocityControlradsPanel);
            app.Motor3Slider.Limits = [0 1];
            app.PositionControlradsPanel = uipanel(app.UIFigure);
            app.PositionControlradsPanel.Title = 'Position Control (rads)';
            app.Motor1Slider_2Label = uilabel(app.PositionControlradsPanel);
            app.Motor1Slider_2Label.Text = 'Motor 1';
            app.Motor1Slider_2 = uislider(app.PositionControlradsPanel);
            app.Motor1Slider_2.Limits = [0 6.28318530717959];
            app.Motor2Slider_2Label = uilabel(app.PositionControlradsPanel);
            app.Motor2Slider_2Label.Text = 'Motor 2';
            app.Motor2Slider_2 = uislider(app.PositionControlradsPanel);
            app.Motor2Slider_2.Limits = [0 6.28318530717959];
            app.Motor3Slider_2Label = uilabel(app.PositionControlradsPanel);
            app.Motor3Slider_2Label.Text = 'Motor 3';
            app.Motor3Slider_2 = uislider(app.PositionControlradsPanel);
            app.Motor3Slider_2.Limits = [0 6.28318530717959];
            app.SensorValuesPanel = uipanel(app.UIFigure);
            app.SensorValuesPanel.Title = 'Sensor Values';
            app.IRSensorGaugeLabel = uilabel(app.SensorValuesPanel);
            app.IRSensorGaugeLabel.Text = 'IR Sensor';
            app.IRSensorGauge = uigauge(app.SensorValuesPanel, 'circular');
            app.UltrasonicSensorGaugeLabel = uilabel(app.SensorValuesPanel);
            app.UltrasonicSensorGaugeLabel.Text = 'Ultrasonic Sensor';
            app.UltrasonicSensorGauge = uigauge(app.SensorValuesPanel, 'circular');
            app.PotentiometerGaugeLabel = uilabel(app.SensorValuesPanel);
            app.PotentiometerGaugeLabel.Text = 'Potentiometer';
            app.PotentiometerGauge = uigauge(app.SensorValuesPanel, 'circular');
            app.PotentiometerGauge.Limits = [0 1023];
            app.ControlMethodSwitchLabel = uilabel(app.UIFigure);
            app.ControlMethodSwitchLabel.Text = {'Control'; 'Method'};
            app.ControlMethodSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ControlMethodSwitch.Items = {'GUI', 'Sensor'};
            app.ControlMethodSwitch.Value = 'Sensor';
            app.ControlTypeSwitchLabel = uilabel(app.UIFigure);
            app.ControlTypeSwitchLabel.Text = {'Control'; 'Type'};
            app.ControlTypeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ControlTypeSwitch.Items = {'Velocity', 'Position'};
            app.ControlTypeSwitch.Value = 'Position';
            app.ButtonStateSwitchLabel = uilabel(app.UIFigure);
            app.ButtonStateSwitchLabel.Enable = 'off';
            app.ButtonStateSwitchLabel.Text = 'Button State';
            app.ButtonStateSwitch = uiswitch(app.UIFigure, 'slider');
            app.ButtonStateSwitch.Enable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = new_matlab_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end