classdef new_matlab_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        VelocityControlradsPanel    matlab.ui.container.Panel
        Motor1Label                 matlab.ui.control.Label
        Motor1Slider                matlab.ui.control.Slider
        Motor2SliderLabel           matlab.ui.control.Label
        Motor2Slider                matlab.ui.control.Slider
        Motor3SliderLabel           matlab.ui.control.Label
        Motor3Slider                matlab.ui.control.Slider
        PositionControlradsPanel    matlab.ui.container.Panel
        Motor1Slider_2Label         matlab.ui.control.Label
        Motor1Slider_2              matlab.ui.control.Slider
        Motor2Slider_2Label         matlab.ui.control.Label
        Motor2Slider_2              matlab.ui.control.Slider
        Motor3Slider_2Label         matlab.ui.control.Label
        Motor3Slider_2              matlab.ui.control.Slider
        SensorValuesPanel           matlab.ui.container.Panel
        IRSensorGaugeLabel          matlab.ui.control.Label
        IRSensorGauge               matlab.ui.control.Gauge
        UltrasonicSensorGaugeLabel  matlab.ui.control.Label
        UltrasonicSensorGauge       matlab.ui.control.Gauge
        PotentiometerGaugeLabel     matlab.ui.control.Label
        PotentiometerGauge          matlab.ui.control.Gauge
        ControlMethodSwitchLabel    matlab.ui.control.Label
        ControlMethodSwitch         matlab.ui.control.ToggleSwitch
        ControlTypeSwitchLabel      matlab.ui.control.Label
        ControlTypeSwitch           matlab.ui.control.ToggleSwitch
    end

    
    properties (Access = private)
        serialHandler
        robot
        cmd
        updateTimer % Description
        sensorVals
    end
    
    methods (Access = private)
        
        function updateTimerCallback(app,~,~)
            msg = fscanf(app.serialHandler);
            [irp, usp, potp] = app.parseMessage(msg);
            
            app.IRSensorGauge.Value = app.sensorVals(1);
            app.UltrasonicSensorGauge.Value = app.sensorVals(2);
            app.PotentiometerSensorGauge.Value = app.sensorVals(3);
            
            app.cmd.position = [irp usp potp];
            app.robot.set(app.cmd);
        end
        
        function [ir, us, pot] = parseMessage(app, msg)
            persistent irp usp potp
            split_vals = strsplit(msg,',');
            try
                % Parse values
                irp = cell2mat(split_vals(1));
                usp = cell2mat(split_vals(2));
                potp = cell2mat(split_vals(3));
                
                app.sensorVals = [str2double(irp) str2double(usp) str2double(potp)];
        
                % Map to 2*pi range
                irp = str2double(irp) * 2 * pi / 60;
                usp = str2double(usp) * 2 * pi / 120;
                potp = 2*pi - str2double(potp) * 2 * pi / 1023;
            
%                 disp("ir: " + irp + "   us: " + usp + "  pot: " + potp)
            catch
            end
            ir = irp;
            us = usp;
            pot = potp;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.serialHandler = serial("COM7", "BaudRate", 9600);
            fclose(app.serialHandler);
            fopen(app.serialHandler);
            
            app.robot = HebiLookup.newGroupFromNames('RoboDutchman', {'joint1', 'joint2', 'joint3'});
            app.cmd = CommandStruct();
            
            period = 0.1;
            app.updateTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', period, ...                     % Period is in seconds
                'BusyMode', 'queue');
            
            app.updateTimer.TimerFcn = @(~,~) app.updateTimerCallback;
            start(app.updateTimer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.serialHandler);    
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.VelocityControlradsPanel = uipanel(app.UIFigure);
            app.VelocityControlradsPanel.Title = 'Velocity Control (rad/s)';
            app.Motor1Label = uilabel(app.VelocityControlradsPanel);
            app.Motor1Label.Text = 'Motor 1';
            app.Motor1Slider = uislider(app.VelocityControlradsPanel);
            app.Motor1Slider.Limits = [0 1];
            app.Motor2SliderLabel = uilabel(app.VelocityControlradsPanel);
            app.Motor2SliderLabel.Text = 'Motor 2';
            app.Motor2Slider = uislider(app.VelocityControlradsPanel);
            app.Motor2Slider.Limits = [0 1];
            app.Motor3SliderLabel = uilabel(app.VelocityControlradsPanel);
            app.Motor3SliderLabel.Text = 'Motor 3';
            app.Motor3Slider = uislider(app.VelocityControlradsPanel);
            app.Motor3Slider.Limits = [0 1];
            app.PositionControlradsPanel = uipanel(app.UIFigure);
            app.PositionControlradsPanel.Title = 'Position Control (rads)';
            app.Motor1Slider_2Label = uilabel(app.PositionControlradsPanel);
            app.Motor1Slider_2Label.Text = 'Motor 1';
            app.Motor1Slider_2 = uislider(app.PositionControlradsPanel);
            app.Motor1Slider_2.Limits = [0 6.28318530717959];
            app.Motor2Slider_2Label = uilabel(app.PositionControlradsPanel);
            app.Motor2Slider_2Label.Text = 'Motor 2';
            app.Motor2Slider_2 = uislider(app.PositionControlradsPanel);
            app.Motor2Slider_2.Limits = [0 6.28318530717959];
            app.Motor3Slider_2Label = uilabel(app.PositionControlradsPanel);
            app.Motor3Slider_2Label.Text = 'Motor 3';
            app.Motor3Slider_2 = uislider(app.PositionControlradsPanel);
            app.Motor3Slider_2.Limits = [0 6.28318530717959];
            app.SensorValuesPanel = uipanel(app.UIFigure);
            app.SensorValuesPanel.Title = 'Sensor Values';
            app.IRSensorGaugeLabel = uilabel(app.SensorValuesPanel);
            app.IRSensorGaugeLabel.Text = 'IR Sensor';
            app.IRSensorGauge = uigauge(app.SensorValuesPanel, 'circular');
            app.UltrasonicSensorGaugeLabel = uilabel(app.SensorValuesPanel);
            app.UltrasonicSensorGaugeLabel.Text = 'Ultrasonic Sensor';
            app.UltrasonicSensorGauge = uigauge(app.SensorValuesPanel, 'circular');
            app.PotentiometerGaugeLabel = uilabel(app.SensorValuesPanel);
            app.PotentiometerGaugeLabel.Text = 'Potentiometer';
            app.PotentiometerGauge = uigauge(app.SensorValuesPanel, 'circular');
            app.PotentiometerGauge.Limits = [0 1023];
            app.ControlMethodSwitchLabel = uilabel(app.UIFigure);
            app.ControlMethodSwitchLabel.Text = {'Control'; 'Method'};
            app.ControlMethodSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ControlMethodSwitch.Items = {'GUI', 'Sensor'};
            app.ControlMethodSwitch.Value = 'GUI';
            app.ControlTypeSwitchLabel = uilabel(app.UIFigure);
            app.ControlTypeSwitchLabel.Text = {'Control'; 'Type'};
            app.ControlTypeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ControlTypeSwitch.Items = {'Velocity', 'Position'};
            app.ControlTypeSwitch.Value = 'Velocity';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_matlab_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end